classdef gui_bci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        SliderTab                     matlab.ui.container.Tab
        alphaSlider                   matlab.ui.control.Slider
        alphaSliderLabel              matlab.ui.control.Label
        sigma_soaSlider               matlab.ui.control.Slider
        sigma_soaSliderLabel          matlab.ui.control.Label
        sigma_c2Slider                matlab.ui.control.Slider
        sigma_c2SliderLabel           matlab.ui.control.Label
        sigma_c1Slider                matlab.ui.control.Slider
        sigma_c1SliderLabel           matlab.ui.control.Label
        p_commonSlider                matlab.ui.control.Slider
        p_commonSlider_2Label         matlab.ui.control.Label
        TextTab                       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        SaveButton                    matlab.ui.control.StateButton
        StartButton                   matlab.ui.control.StateButton
        SetupPanel                    matlab.ui.container.Panel
        exposuretrialsEditField       matlab.ui.control.NumericEditField
        exposuretrialsEditFieldLabel  matlab.ui.control.Label
        simtrialsEditField            matlab.ui.control.NumericEditField
        simtrialsEditFieldLabel       matlab.ui.control.Label
        DistributionPanel             matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SimulatedrecalibrationBayesiancausalinferencemodelPanel  matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        setup % Description
        para
        saveResults
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: p_commonSlider
        function p_commonSliderValueChanging(app, event)
            app.para.p_common = event.Value;

        end

        % Value changing function: sigma_c1Slider
        function sigma_c1SliderValueChanging(app, event)
            app.para.sigma_c1 = event.Value;

        end

        % Value changing function: sigma_c2Slider
        function sigma_c2SliderValueChanging(app, event)
            app.para.sigma_c2 = event.Value;

        end

        % Value changing function: sigma_soaSlider
        function sigma_soaSliderValueChanging(app, event)
            app.para.sigma_soa = event.Value;

        end

        % Value changing function: alphaSlider
        function alphaSliderValueChanging(app, event)
            app.para.alpha = event.Value;

        end

        % Value changed function: simtrialsEditField
        function simtrialsEditFieldValueChanged(app, event)
            app.setup.simTrial = app.simtrialsEditField.Value;

        end

        % Value changed function: exposuretrialsEditField
        function exposuretrialsEditFieldValueChanged(app, event)
            app.setup.expTrial = app.exposuretrialsEditField.Value;

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;

            %% set up parameters
            simTrial = app.setup.simTrial;
            expTrial = app.setup.expTrial;
            adaptor_soas = [-700,-300:100:300, 700]./1000;
            app.setup.adaptor_soas = adaptor_soas;

            %% get free parameters
            p_common = app.para.p_common;
            sigma_c1 = app.para.sigma_c1;
            sigma_c2 = app.para.sigma_c2;
            sigma_soa = app.para.sigma_soa;
            alpha = app.para.alpha;

            %% running simulation
            for i                 = 1:numel(adaptor_soas)

                adaptor_soa                   = adaptor_soas(i);% in s, adaptor_soa, fixed in session

                for t                 = 1:simTrial

                    delta_s = update_recal_bayesian_MA(expTrial, adaptor_soa, ...
                        p_common, sigma_soa, sigma_c1, sigma_c2, alpha);

                    last_recal     = - delta_s(:, end);

                end
            end

            %% summarize recalibration effetct
            last_recal_iSOA_error = std(last_recal, [], 2);
            last_recal_iSOA       = mean(last_recal, 2);

            %% plotting
            axes(app.UIAxes)
            set(gca,'FontSize',15,'linewidth',2); hold on;
            errorbar(adaptor_soas, last_recal_iSOA, last_recal_iSOA_error,'.','LineWidth',2); hold off;
            yline(0)
            xticks(adaptor_soas)
            xticklabels(adaptor_soas)
            xlabel('adaptor SOA (s)')
            ylabel('recalibration (s)')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SimulatedrecalibrationBayesiancausalinferencemodelPanel = uipanel(app.GridLayout);
            app.SimulatedrecalibrationBayesiancausalinferencemodelPanel.Title = 'Simulated recalibration (Bayesian causal inference model)';
            app.UIAxes = uiaxes(app.SimulatedrecalibrationBayesiancausalinferencemodelPanel);
            xlabel(app.UIAxes, 'Adaptor SOA')
            ylabel(app.UIAxes, 'Recalibration effect')
            zlabel(app.UIAxes, 'Z')
            app.DistributionPanel = uipanel(app.GridLayout);
            app.DistributionPanel.Title = 'Distribution';
            app.GridLayout3 = uigridlayout(app.DistributionPanel);
            app.SetupPanel = uipanel(app.GridLayout);
            app.SetupPanel.Title = 'Set up';
            app.simtrialsEditFieldLabel = uilabel(app.SetupPanel);
            app.simtrialsEditFieldLabel.Text = 'sim trials';
            app.simtrialsEditField = uieditfield(app.SetupPanel, 'numeric');
            app.simtrialsEditField.ValueChangedFcn = createCallbackFcn(app, @simtrialsEditFieldValueChanged, true);
            app.simtrialsEditField.Value = 1000;
            app.exposuretrialsEditFieldLabel = uilabel(app.SetupPanel);
            app.exposuretrialsEditFieldLabel.Text = 'exposure trials';
            app.exposuretrialsEditField = uieditfield(app.SetupPanel, 'numeric');
            app.exposuretrialsEditField.ValueChangedFcn = createCallbackFcn(app, @exposuretrialsEditFieldValueChanged, true);
            app.exposuretrialsEditField.Value = 250;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartButton = uibutton(app.GridLayout2, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.GridLayout2, 'state');
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SliderTab = uitab(app.TabGroup);
            app.SliderTab.Title = 'Slider';
            app.p_commonSlider_2Label = uilabel(app.SliderTab);
            app.p_commonSlider_2Label.Text = 'p_common';
            app.p_commonSlider = uislider(app.SliderTab);
            app.p_commonSlider.Limits = [0.001 1];
            app.p_commonSlider.MajorTicks = [0.001 1];
            app.p_commonSlider.ValueChangingFcn = createCallbackFcn(app, @p_commonSliderValueChanging, true);
            app.p_commonSlider.Value = 0.3;
            app.sigma_c1SliderLabel = uilabel(app.SliderTab);
            app.sigma_c1SliderLabel.Text = 'sigma_c1';
            app.sigma_c1Slider = uislider(app.SliderTab);
            app.sigma_c1Slider.Limits = [0.001 1];
            app.sigma_c1Slider.MajorTicks = [0.001 1];
            app.sigma_c1Slider.ValueChangingFcn = createCallbackFcn(app, @sigma_c1SliderValueChanging, true);
            app.sigma_c1Slider.Value = 0.001;
            app.sigma_c2SliderLabel = uilabel(app.SliderTab);
            app.sigma_c2SliderLabel.Text = 'sigma_c2';
            app.sigma_c2Slider = uislider(app.SliderTab);
            app.sigma_c2Slider.Limits = [0.001 2];
            app.sigma_c2Slider.MajorTicks = [0.001 2];
            app.sigma_c2Slider.ValueChangingFcn = createCallbackFcn(app, @sigma_c2SliderValueChanging, true);
            app.sigma_c2Slider.Value = 1;
            app.sigma_soaSliderLabel = uilabel(app.SliderTab);
            app.sigma_soaSliderLabel.Text = 'sigma_soa';
            app.sigma_soaSlider = uislider(app.SliderTab);
            app.sigma_soaSlider.Limits = [0.01 0.4];
            app.sigma_soaSlider.MajorTicks = [0.01 0.4];
            app.sigma_soaSlider.ValueChangingFcn = createCallbackFcn(app, @sigma_soaSliderValueChanging, true);
            app.sigma_soaSlider.Value = 0.13;
            app.alphaSliderLabel = uilabel(app.SliderTab);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.SliderTab);
            app.alphaSlider.Limits = [0.001 0.3];
            app.alphaSlider.MajorTicks = [0.001 0.3];
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderValueChanging, true);
            app.alphaSlider.Value = 0.01;
            app.TextTab = uitab(app.TabGroup);
            app.TextTab.Title = 'Text';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_bci

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end