classdef modelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        tauEditField                  matlab.ui.control.NumericEditField
        tauEditFieldLabel             matlab.ui.control.Label
        tauSlider                     matlab.ui.control.Slider
        tauSliderLabel                matlab.ui.control.Label
        freeparametersPanel           matlab.ui.container.Panel
        cEditField_2                  matlab.ui.control.NumericEditField
        cEditField_2Label             matlab.ui.control.Label
        criterionSlider_2             matlab.ui.control.Slider
        criterionSlider_2Label        matlab.ui.control.Label
        sigma_vSlider                 matlab.ui.control.Slider
        sigma_vSliderLabel            matlab.ui.control.Label
        usesingleinputCheckBox        matlab.ui.control.CheckBox
        multipleinputsEditField       matlab.ui.control.EditField
        multipleinputsEditFieldLabel  matlab.ui.control.Label
        usemultipleinputsCheckBox     matlab.ui.control.CheckBox
        sigma_vEditField              matlab.ui.control.NumericEditField
        mu_C1EditFieldLabel           matlab.ui.control.Label
        startButton                   matlab.ui.control.Button
        simtrialEditField             matlab.ui.control.NumericEditField
        simtrialEditFieldLabel        matlab.ui.control.Label
        lambdaEditField               matlab.ui.control.NumericEditField
        lambdaEditFieldLabel          matlab.ui.control.Label
        lambdaSlider                  matlab.ui.control.Slider
        lambdaSliderLabel             matlab.ui.control.Label
        c_updateEditField             matlab.ui.control.NumericEditField
        c_updateEditFieldLabel        matlab.ui.control.Label
        c_updateSlider                matlab.ui.control.Slider
        c_updateSliderLabel           matlab.ui.control.Label
        alphaEditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        alphaSlider                   matlab.ui.control.Slider
        alphaSliderLabel              matlab.ui.control.Label
        sigma_aEditField              matlab.ui.control.NumericEditField
        sigma_aEditFieldLabel         matlab.ui.control.Label
        sigma_aSlider                 matlab.ui.control.Slider
        sigma_aSliderLabel            matlab.ui.control.Label
        usesliderCheckBox             matlab.ui.control.CheckBox
        UIAxes2_18                    matlab.ui.control.UIAxes
        UIAxes2_17                    matlab.ui.control.UIAxes
        UIAxes2_16                    matlab.ui.control.UIAxes
        UIAxes2_15                    matlab.ui.control.UIAxes
        UIAxes2_14                    matlab.ui.control.UIAxes
        UIAxes2_13                    matlab.ui.control.UIAxes
        UIAxes2_12                    matlab.ui.control.UIAxes
        UIAxes2_11                    matlab.ui.control.UIAxes
        UIAxes2_9                     matlab.ui.control.UIAxes
        UIAxes2_8                     matlab.ui.control.UIAxes
        UIAxes2_7                     matlab.ui.control.UIAxes
        UIAxes2_6                     matlab.ui.control.UIAxes
        UIAxes2_5                     matlab.ui.control.UIAxes
        UIAxes2_4                     matlab.ui.control.UIAxes
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2_10                    matlab.ui.control.UIAxes
        UIAxes2_1                     matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            global model param

            
            % read set up parameters
            model.expo_num_sim = app.simtrialEditField.Value;

            % read free parameters
            if app.usesliderCheckBox.Value            == 1 % use slider
                param.tau          = app.tauSlider.Value;
                param.sigma_a      = app.sigma_aSlider.Value;
                param.sigma_v      = app.sigma_vSlider.Value;
                param.c            = app.criterionSlider_2.Value;
                param.c_update     = app.c_updateSlider.Value;
                param.lambda       = app.lambdaSlider.Value;

                param.alpha        = app.alphaSlider.Value;

            elseif app.usesingleinputCheckBox.Value   == 1 % use text box
                param.tau          = app.tauEditField.Value;
                param.sigma_a      = app.sigma_aEditField.Value;
                param.sigma_v      = app.sigma_vEditField.Value;
                param.c            = app.cEditField_2.Value;
                param.c_update     = app.c_updateEditField.Value;
                param.lambda       = app.lambdaEditField.Value;
                param.alpha        = app.alphaEditField.Value;

            elseif app.usemultipleinputsCheckBox.Value== 1 % use the inputs of multiple 

                ps= str2double(strsplit(app.multipleinputsEditField.Value));
                param.tau          = ps(1);
                param.sigma_a      = ps(2);
                param.sigma_v      = ps(3);
                param.c            = ps(4);
                param.c_update     = ps(5);
                param.lambda       = ps(6); % weight
                param.alpha        = ps(7);
       

            end

            [projectDir, ~]= fileparts(fileparts(pwd));
            addpath(genpath(fullfile(projectDir, 'utils')));

            % set fixed & set-up parameters
            model.mode         = 'predict';
            model.thres_R2 = 0.95;
            model.expo_num_sim = 1000; % number of simulation for exposure phase
            model.expo_num_trial = 250; % number of *real* trials in exposure phase
            model.num_bin  = 100; % numer of bin to approximate tau_shift distribution
            model.bound_full = 10*1e3; % in second, the bound for prior axis
            model.bound_int = 1.4*1e3; % in second, where measurements are likely to reside
            model.num_sample = 1e3; % number of samples for simulating psychometric function with causal inference, only used in pmf_exp_CI
            model.test_soa = [-0.5, -0.3:0.05:0.3, 0.5]*1e3;
            model.sim_adaptor_soa  = [-0.7, -0.3:0.1:0.3, 0.7]*1e3;
            model.toj_axis_finer = 1; % simulate pmf with finer axis
            model.adaptor_axis_finer = 0;

            tic
            model.mode       = 'predict';
            pred =  nll_criteria_asym([param.tau, param.sigma_a, param.sigma_v,...
                param.c, param.c_update, param.lambda, param.alpha], model, []);
            toc

            %% plot

            %% recalibration figure

            % summarize
            mu_shift_iSOA      = mean(pred.pss_shift, 2);
            mu_shift_iSOA_error= std(pred.pss_shift, [], 2);
            errorbar(app.UIAxes,  model.sim_adaptor_soa , mu_shift_iSOA, mu_shift_iSOA_error,'k','LineWidth',2)
            yline(app.UIAxes, 0)
            xline(app.UIAxes, 0)
            xlabel(app.UIAxes, 'adapter SOA')
            ylabel(app.UIAxes, 'shift of PSS')

            ylim(app.UIAxes, [-200 200])
            xticks(app.UIAxes, model.sim_adaptor_soa)
            xticklabels(app.UIAxes, model.sim_adaptor_soa)
            paramVal           = sprintf('tau=%.2f, sigma_a=%.2f, sigma_v=%.2f,\n criterion=%.2f, lambda=%.2f, alpha=%.2f', ...
                param.tau, param.sigma_a, param.sigma_v,...
                param.c, param.lambda, param.alpha);
            title(app.UIAxes,paramVal)

            %% TOJ figures

            for i              = 1:9

                %% pretest
                plot(eval(sprintf('app.UIAxes2_%i',i)), pred.test_soa, pred.pre_pmf, 'LineWidth',1.5)
                xline(eval(sprintf('app.UIAxes2_%i',i)), -param.tau,'LineWidth',1.5)
                title(eval(sprintf('app.UIAxes2_%i',i)),'pretest')

                %% posttest
                plot(eval(sprintf('app.UIAxes2_%i',i+9)), pred.test_soa, squeeze(pred.post_pmf(i,:,:)), 'LineWidth',1.5)
                xline(eval(sprintf('app.UIAxes2_%i',i+9)), -(param.tau + mean(pred.tau_shift(i))),'LineWidth',1.5)
                title(eval(sprintf('app.UIAxes2_%i',i+9)),sprintf('adpator = %.2f', model.sim_adaptor_soa(i)))
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2_1 = uiaxes(app.UIFigure);
            title(app.UIAxes2_1, 'Title')
            app.UIAxes2_1.XTick = [0 0.5 1];
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            title(app.UIAxes2_10, 'Title')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            app.UIAxes2_2.XTick = [0 0.5 1];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Title')
            app.UIAxes2_3.XTick = [0 0.5 1];
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, 'Title')
            app.UIAxes2_4.XTick = [0 0.5 1];
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            title(app.UIAxes2_5, 'Title')
            app.UIAxes2_5.XTick = [0 0.5 1];
            app.UIAxes2_6 = uiaxes(app.UIFigure);
            title(app.UIAxes2_6, 'Title')
            app.UIAxes2_6.XTick = [0 0.5 1];
            app.UIAxes2_7 = uiaxes(app.UIFigure);
            title(app.UIAxes2_7, 'Title')
            app.UIAxes2_7.XTick = [0 0.5 1];
            app.UIAxes2_8 = uiaxes(app.UIFigure);
            title(app.UIAxes2_8, 'Title')
            app.UIAxes2_8.XTick = [0 0.5 1];
            app.UIAxes2_9 = uiaxes(app.UIFigure);
            title(app.UIAxes2_9, 'Title')
            app.UIAxes2_9.XTick = [0 0.5 1];
            app.UIAxes2_11 = uiaxes(app.UIFigure);
            title(app.UIAxes2_11, 'Title')
            app.UIAxes2_12 = uiaxes(app.UIFigure);
            title(app.UIAxes2_12, 'Title')
            app.UIAxes2_13 = uiaxes(app.UIFigure);
            title(app.UIAxes2_13, 'Title')
            app.UIAxes2_14 = uiaxes(app.UIFigure);
            title(app.UIAxes2_14, 'Title')
            app.UIAxes2_15 = uiaxes(app.UIFigure);
            title(app.UIAxes2_15, 'Title')
            app.UIAxes2_16 = uiaxes(app.UIFigure);
            title(app.UIAxes2_16, 'Title')
            app.UIAxes2_17 = uiaxes(app.UIFigure);
            title(app.UIAxes2_17, 'Title')
            app.UIAxes2_18 = uiaxes(app.UIFigure);
            title(app.UIAxes2_18, 'Title')
            app.freeparametersPanel = uipanel(app.UIFigure);
            app.freeparametersPanel.Title = 'free parameters';
            app.usesliderCheckBox = uicheckbox(app.freeparametersPanel);
            app.usesliderCheckBox.Text = 'use slider';
            app.sigma_aSliderLabel = uilabel(app.freeparametersPanel);
            app.sigma_aSliderLabel.Text = 'sigma_a';
            app.sigma_aSlider = uislider(app.freeparametersPanel);
            app.sigma_aSlider.Limits = [10 200];
            app.sigma_aSlider.MajorTicks = [10 100 200];
            app.sigma_aSlider.MajorTickLabels = {'10', '100', '200'};
            app.sigma_aSlider.MinorTicks = [];
            app.sigma_aSlider.Value = 64;
            app.sigma_aEditFieldLabel = uilabel(app.freeparametersPanel);
            app.sigma_aEditFieldLabel.Text = 'sigma_a';
            app.sigma_aEditField = uieditfield(app.freeparametersPanel, 'numeric');
            app.sigma_aEditField.Limits = [10 100];
            app.sigma_aEditField.Value = 30;
            app.alphaSliderLabel = uilabel(app.freeparametersPanel);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.freeparametersPanel);
            app.alphaSlider.Limits = [0.01 2];
            app.alphaSlider.MajorTicks = [0.01 2];
            app.alphaSlider.MajorTickLabels = {''};
            app.alphaSlider.Value = 0.01;
            app.alphaEditFieldLabel = uilabel(app.freeparametersPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.freeparametersPanel, 'numeric');
            app.alphaEditField.Limits = [0.001 0.3];
            app.alphaEditField.Value = 0.002;
            app.c_updateSliderLabel = uilabel(app.freeparametersPanel);
            app.c_updateSliderLabel.Text = 'c_update';
            app.c_updateSlider = uislider(app.freeparametersPanel);
            app.c_updateSlider.Limits = [1 350];
            app.c_updateSlider.MajorTicks = [1 350];
            app.c_updateSlider.MajorTickLabels = {'1', '350'};
            app.c_updateSlider.MinorTicks = [];
            app.c_updateSlider.Value = 87;
            app.c_updateEditFieldLabel = uilabel(app.freeparametersPanel);
            app.c_updateEditFieldLabel.Text = 'c_update';
            app.c_updateEditField = uieditfield(app.freeparametersPanel, 'numeric');
            app.c_updateEditField.Limits = [1 350];
            app.c_updateEditField.Value = 100;
            app.lambdaSliderLabel = uilabel(app.freeparametersPanel);
            app.lambdaSliderLabel.Text = 'lambda';
            app.lambdaSlider = uislider(app.freeparametersPanel);
            app.lambdaSlider.Limits = [0.001 0.06];
            app.lambdaSlider.MajorTicks = [0.001 0.06];
            app.lambdaSlider.MajorTickLabels = {'0.001', '0.06', ''};
            app.lambdaSlider.Value = 0.016;
            app.lambdaEditFieldLabel = uilabel(app.freeparametersPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.freeparametersPanel, 'numeric');
            app.lambdaEditField.Limits = [0.001 0.06];
            app.lambdaEditField.Value = 0.06;
            app.simtrialEditFieldLabel = uilabel(app.freeparametersPanel);
            app.simtrialEditFieldLabel.Text = 'sim trial';
            app.simtrialEditField = uieditfield(app.freeparametersPanel, 'numeric');
            app.simtrialEditField.Limits = [1 100];
            app.simtrialEditField.Value = 1;
            app.startButton = uibutton(app.freeparametersPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.mu_C1EditFieldLabel = uilabel(app.freeparametersPanel);
            app.mu_C1EditFieldLabel.Text = 'sigma_v';
            app.sigma_vEditField = uieditfield(app.freeparametersPanel, 'numeric');
            app.sigma_vEditField.Limits = [30 200];
            app.sigma_vEditField.Value = 60;
            app.usemultipleinputsCheckBox = uicheckbox(app.freeparametersPanel);
            app.usemultipleinputsCheckBox.Text = 'use multiple inputs';
            app.multipleinputsEditFieldLabel = uilabel(app.freeparametersPanel);
            app.multipleinputsEditFieldLabel.Text = 'multiple inputs';
            app.multipleinputsEditField = uieditfield(app.freeparametersPanel, 'text');
            app.usesingleinputCheckBox = uicheckbox(app.freeparametersPanel);
            app.usesingleinputCheckBox.Text = 'use single input';
            app.sigma_vSliderLabel = uilabel(app.freeparametersPanel);
            app.sigma_vSliderLabel.Text = 'sigma_v';
            app.sigma_vSlider = uislider(app.freeparametersPanel);
            app.sigma_vSlider.Limits = [10 200];
            app.sigma_vSlider.MajorTicks = [10 100 200];
            app.sigma_vSlider.MajorTickLabels = {'10', '100', '200'};
            app.sigma_vSlider.MinorTicks = [];
            app.sigma_vSlider.Value = 64;
            app.criterionSlider_2Label = uilabel(app.freeparametersPanel);
            app.criterionSlider_2Label.Text = 'criterion';
            app.criterionSlider_2 = uislider(app.freeparametersPanel);
            app.criterionSlider_2.Limits = [1 350];
            app.criterionSlider_2.MajorTicks = [1 350];
            app.criterionSlider_2.MajorTickLabels = {'1', '350'};
            app.criterionSlider_2.MinorTicks = [];
            app.criterionSlider_2.Value = 87;
            app.cEditField_2Label = uilabel(app.freeparametersPanel);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.freeparametersPanel, 'numeric');
            app.cEditField_2.Limits = [1 350];
            app.cEditField_2.Value = 100;
            app.tauSliderLabel = uilabel(app.UIFigure);
            app.tauSliderLabel.Text = 'tau';
            app.tauSlider = uislider(app.UIFigure);
            app.tauSlider.Limits = [-200 200];
            app.tauSlider.MajorTicks = [-200 200];
            app.tauSlider.MajorTickLabels = {'-200', '200'};
            app.tauSlider.MinorTicks = [];
            app.tauSlider.Value = 1.3;
            app.tauEditFieldLabel = uilabel(app.UIFigure);
            app.tauEditFieldLabel.Text = 'tau';
            app.tauEditField = uieditfield(app.UIFigure, 'numeric');
            app.tauEditField.Limits = [-200 200];
            app.tauEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end